classdef lagrangian_point < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        showtrajectoriesSwitch       matlab.ui.control.Switch
        showtrajectoriesSwitchLabel  matlab.ui.control.Label
        PlotButton                   matlab.ui.control.Button
        M1M2EditField                matlab.ui.control.NumericEditField
        M1M2EditFieldLabel           matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        risthedistancebetweenM1andM2Label  matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            syms x y
            G = 1;
            M1 = app.M1M2EditField.Value;
            M2 = 1;
            x1 = 0;
            y1 = 1;
            x2 = 0;
            y2 = -M1/M2;
            plot(app.UIAxes,x1,y1,'o','MarkerSize',3)
            lab = strcat("M1(",string(round(x1/(1+M1/M2),3,"significant")),"r,",string(round(y1/(1+M1/M2),3,"significant")),"r)");
            text(app.UIAxes,x1,y1,lab)
            hold(app.UIAxes,"on")
            plot(app.UIAxes,x2,y2,'o','MarkerSize',3)
            lab = strcat("M2(",string(round(x2/(1+M1/M2),3,"significant")),"r,",string(round(y2/(1+M1/M2),3,"significant")),"r)");
            text(app.UIAxes,x2,y2,lab)
            hold(app.UIAxes,"on")
            a = abs(y1-y2);
            w = sqrt(2*G*(M1+M2)*(1/abs(y1-y2)-1/2/a)/(y1-y2)^2);
            v1x = w*(-y1);
            v1y = w*(x1);
            v2x = w*(-y2);
            v2y = w*(x2);
            x0 = [v1x v1y x1 y1 v2x v2y x2 y2 0 0 0 0];
            tau = sqrt(4*pi^2*a^3/G/(M1+M2));
            tspan = [0:tau/1e5:tau*1.01];
            w = sqrt(G*(M1+M2)*((x2-x1)^2+(y2-y1)^2)^(-1.5));
            eqnx = -M1*((x-x1)^2+(y-y1)^2)^(-1.5)*(x-x1) - M2*((x-x2)^2+(y-y2)^2)^(-1.5)*(x-x2) ...
                + (M1+M2)*((x2-x1)^2+(y2-y1)^2)^(-1.5)*x == 0;
            eqny = -M1*((x-x1)^2+(y-y1)^2)^(-1.5)*(y-y1) - M2*((x-x2)^2+(y-y2)^2)^(-1.5)*(y-y2) ...
                + (M1+M2)*((x2-x1)^2+(y2-y1)^2)^(-1.5)*y == 0;
            [x,y] = solve(eqnx,eqny);

            for i = 1:length(y)
                xt = vpa(x(i));
                yt = vpa(y(i));
                if yt == real(yt) & xt == real(xt)
                    if abs(M1*((xt-x1)^2+(yt-y1)^2)^(-1.5)*(yt-y1) + M2*((xt-x2)^2+(yt-y2)^2)^(-1.5)*(yt-y2) ...
                        - (M1+M2)*((x2-x1)^2+(y2-y1)^2)^(-1.5)*yt) < 0.00001 ...
                        & abs(M1*((xt-x1)^2+(yt-y1)^2)^(-1.5)*(xt-x1) + M2*((xt-x2)^2+(yt-y2)^2)^(-1.5)*(xt-x2) ...
                        - (M1+M2)*((x2-x1)^2+(y2-y1)^2)^(-1.5)*xt) < 0.00001
                        lab = strcat("(",string(round(xt/(1+M1/M2),3,"significant")),"r,",string(round(yt/(1+M1/M2),3,"significant")),"r)");
                        plot(app.UIAxes,xt,yt,'o')
                        text(app.UIAxes,xt,yt,lab)
                        hold(app.UIAxes,"on")
                        set(app.UIAxes,'DataAspectRatio',[1 1 1])

                        x0(9) = w*(-yt);
                        x0(10) = w*(xt);
                        x0(11) = xt;
                        x0(12) = yt;

                        disp(M1*((xt-x1)^2+(yt-y1)^2)^(-1.5)*(yt-y1) + M2*((xt-x2)^2+(yt-y2)^2)^(-1.5)*(yt-y2))
                        disp((M1+M2)*((x2-x1)^2+(y2-y1)^2)^(-1.5)*yt)
                        disp("-----------------------------")
                        [t,X] = ode45(@(t,X) odefcn(t,G,M1,M2,X),tspan,x0);
                        plot(app.UIAxes, X(:,3),X(:,4))
                        hold(app.UIAxes,"on")
                        plot(app.UIAxes, X(:,7),X(:,8))
                        hold(app.UIAxes,"on")
                        if (app.showtrajectoriesSwitch.Value == "On")
                            plot(app.UIAxes, X(:,11),X(:,12))
                            hold(app.UIAxes,"on")
                        end
                    end
                end
            end
            function dxdt = odefcn(t,G,M1,M2,X)
                dxdt = zeros(12,1);
                dxdt(1) = G*M2*((X(7)-X(3))^2+(X(8)-X(4))^2)^(-1.5)*(X(7)-X(3));
                dxdt(2) = G*M2*((X(7)-X(3))^2+(X(8)-X(4))^2)^(-1.5)*(X(8)-X(4));
                dxdt(3) = X(1);
                dxdt(4) = X(2);
                dxdt(5) = -G*M1*((X(7)-X(3))^2+(X(8)-X(4))^2)^(-1.5)*(X(7)-X(3));
                dxdt(6) = -G*M1*((X(7)-X(3))^2+(X(8)-X(4))^2)^(-1.5)*(X(8)-X(4));
                dxdt(7) = X(5);
                dxdt(8) = X(6);
                %dxdt(9) = -G*M1*((X(11)-X(3))^2+(X(12)-X(4))^2)^(-1.5)*(X(11)-X(3)) ...
                    %- G*M2*((X(11)-X(7))^2+(X(12)-X(8))^2)^(-1.5)*(X(11)-X(7));
                dxdt(9) = -G*(M1+M2)*((X(7)-X(3))^2+(X(8)-X(4))^2)^(-1.5)*X(11);
                %dxdt(10) = -G*M1*((X(11)-X(3))^2+(X(12)-X(4))^2)^(-1.5)*(X(12)-X(4)) ...
                    %- G*M2*((X(11)-X(7))^2+(X(12)-X(8))^2)^(-1.5)*(X(12)-X(8));
                dxdt(10) = -G*(M1+M2)*((X(7)-X(3))^2+(X(8)-X(4))^2)^(-1.5)*X(12);
                dxdt(11) = X(9);
                dxdt(12) = X(10);
            end
        hold(app.UIAxes,"off")


        end

        % Value changed function: M1M2EditField
        function M1M2EditFieldValueChanged(app, event)
            value = app.M1M2EditField.Value;
            
        end

        % Value changed function: showtrajectoriesSwitch
        function showtrajectoriesSwitchValueChanged(app, event)
            value = app.showtrajectoriesSwitch.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {168, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.M1M2EditFieldLabel = uilabel(app.LeftPanel);
            app.M1M2EditFieldLabel.Text = 'M1/M2';
            app.M1M2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.M1M2EditField.ValueChangedFcn = createCallbackFcn(app, @M1M2EditFieldValueChanged, true);
            app.PlotButton = uibutton(app.LeftPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.showtrajectoriesSwitchLabel = uilabel(app.LeftPanel);
            app.showtrajectoriesSwitchLabel.Text = 'show trajectories';
            app.showtrajectoriesSwitch = uiswitch(app.LeftPanel, 'slider');
            app.showtrajectoriesSwitch.ValueChangedFcn = createCallbackFcn(app, @showtrajectoriesSwitchValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Lagrangian Points')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.risthedistancebetweenM1andM2Label = uilabel(app.RightPanel);
            app.risthedistancebetweenM1andM2Label.Text = '* r is the distance between M1 and M2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lagrangian_point

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end